classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        InversePanel                   matlab.ui.container.Panel
        InverseThetaEditField          matlab.ui.control.NumericEditField
        D0Label_41                     matlab.ui.control.Label
        InverseLEditField              matlab.ui.control.NumericEditField
        D0Label_40                     matlab.ui.control.Label
        HomomorphicAmountEditField_11  matlab.ui.control.NumericEditField
        D0Label_18                     matlab.ui.control.Label
        HomomorphicAmountEditField_8   matlab.ui.control.NumericEditField
        D0Label_15                     matlab.ui.control.Label
        HomomorphicModeDropDown_6      matlab.ui.control.DropDown
        PaddingLabel_10                matlab.ui.control.Label
        Image4_7                       matlab.ui.control.Image
        Image4_8                       matlab.ui.control.Image
        ReferenceImagePanel            matlab.ui.container.Panel
        BLabel_3                       matlab.ui.control.Label
        GLabel_3                       matlab.ui.control.Label
        RLabel_3                       matlab.ui.control.Label
        BReferenceImage                matlab.ui.control.Image
        GReferenceImage                matlab.ui.control.Image
        RReferenceImage                matlab.ui.control.Image
        FourierSpectrumLabel_3         matlab.ui.control.Label
        BeforeImage_14                 matlab.ui.control.Image
        InputImageLabel_3              matlab.ui.control.Label
        BeforeStats_5                  matlab.ui.control.ListBox
        ResultImagePanel               matlab.ui.container.Panel
        BLabel_2                       matlab.ui.control.Label
        GLabel_2                       matlab.ui.control.Label
        RLabel_2                       matlab.ui.control.Label
        BResultImage                   matlab.ui.control.Image
        GResultImage                   matlab.ui.control.Image
        RResultImage                   matlab.ui.control.Image
        FourierSpectrumLabel_2         matlab.ui.control.Label
        BeforeImage_10                 matlab.ui.control.Image
        InputImageLabel_2              matlab.ui.control.Label
        BeforeStats_4                  matlab.ui.control.ListBox
        Image3_3                       matlab.ui.control.Image
        Image4_6                       matlab.ui.control.Image
        referenceImagePanel            matlab.ui.container.Panel
        UploadImageButton_2            matlab.ui.control.Button
        Image4_5                       matlab.ui.control.Image
        FilterResponsePanel            matlab.ui.container.Panel
        BeforeImage_9                  matlab.ui.control.Image
        BeforeStats_3                  matlab.ui.control.ListBox
        Image4_3                       matlab.ui.control.Image
        Image4_2                       matlab.ui.control.Image
        Image3_2                       matlab.ui.control.Image
        Image3                         matlab.ui.control.Image
        Image4                         matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        InputImagePanel_2              matlab.ui.container.Panel
        UploadImageButton              matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        UndoButton                     matlab.ui.control.Button
        ApplyButton                    matlab.ui.control.Button
        MethodsButtonGroup             matlab.ui.container.ButtonGroup
        WienerButton                   matlab.ui.control.ToggleButton
        InverseButton                  matlab.ui.control.ToggleButton
        NotchButton                    matlab.ui.control.ToggleButton
        GaussianButton                 matlab.ui.control.ToggleButton
        ButterworthButton              matlab.ui.control.ToggleButton
        PSFButton                      matlab.ui.control.ToggleButton
        Label                          matlab.ui.control.Label
        IdealButton                    matlab.ui.control.ToggleButton
        PeriodicNoiseRestorationLabel  matlab.ui.control.Label
        BHPFButton                     matlab.ui.control.ToggleButton
        GHPFButton                     matlab.ui.control.ToggleButton
        DCButton                       matlab.ui.control.ToggleButton
        BLPFButton                     matlab.ui.control.ToggleButton
        GLPFButton                     matlab.ui.control.ToggleButton
        HomomorphicButton              matlab.ui.control.ToggleButton
        BrightnessLabel                matlab.ui.control.Label
        HighPassFilterLabel            matlab.ui.control.Label
        LowPassFilterLabel             matlab.ui.control.Label
        IHPFButton                     matlab.ui.control.ToggleButton
        ILPFButton                     matlab.ui.control.ToggleButton
        InputImagePanel                matlab.ui.container.Panel
        InspectSpectrumButton          matlab.ui.control.Button
        BLabel                         matlab.ui.control.Label
        GLabel                         matlab.ui.control.Label
        RLabel                         matlab.ui.control.Label
        BInputImage                    matlab.ui.control.Image
        GInputImage                    matlab.ui.control.Image
        RInputImage                    matlab.ui.control.Image
        FourierSpectrumLabel           matlab.ui.control.Label
        BeforeImage                    matlab.ui.control.Image
        InputImageLabel                matlab.ui.control.Label
        BeforeStats                    matlab.ui.control.ListBox
        NotchPanel                     matlab.ui.container.Panel
        NotchDetectButton              matlab.ui.control.Button
        NotchCentersTextArea           matlab.ui.control.TextArea
        NotchCentersTextAreaLabel      matlab.ui.control.Label
        CentersLabel                   matlab.ui.control.Label
        HomomorphicAmountEditField_30  matlab.ui.control.NumericEditField
        D0Label_39                     matlab.ui.control.Label
        PaddingLabel_15                matlab.ui.control.Label
        HomomorphicAmountEditField_25  matlab.ui.control.NumericEditField
        D0Label_32                     matlab.ui.control.Label
        HomomorphicAmountEditField_24  matlab.ui.control.NumericEditField
        D0Label_31                     matlab.ui.control.Label
        HomomorphicAmountEditField_23  matlab.ui.control.NumericEditField
        D0Label_30                     matlab.ui.control.Label
        HomomorphicModeDropDown_10     matlab.ui.control.DropDown
        PaddingLabel_14                matlab.ui.control.Label
        HomomorphicModeDropDown_11     matlab.ui.control.DropDown
        IHPFPanel                      matlab.ui.container.Panel
        IHPFModeDropDown_2             matlab.ui.control.DropDown
        PaddingLabel_6                 matlab.ui.control.Label
        IHPFAmountEditField            matlab.ui.control.NumericEditField
        D0Label_8                      matlab.ui.control.Label
        IHPFModeDropDown               matlab.ui.control.DropDown
        PaddingLabel_5                 matlab.ui.control.Label
        DCPanel                        matlab.ui.container.Panel
        HomomorphicAmountEditField_29  matlab.ui.control.NumericEditField
        D0Label_38                     matlab.ui.control.Label
        HomomorphicAmountEditField_28  matlab.ui.control.NumericEditField
        D0Label_37                     matlab.ui.control.Label
        HomomorphicPanel               matlab.ui.container.Panel
        HomomorphicAmountEditField_7   matlab.ui.control.NumericEditField
        D0Label_14                     matlab.ui.control.Label
        HomomorphicAmountEditField_6   matlab.ui.control.NumericEditField
        D0Label_13                     matlab.ui.control.Label
        HomomorphicAmountEditField_5   matlab.ui.control.NumericEditField
        D0Label_12                     matlab.ui.control.Label
        HomomorphicAmountEditField_4   matlab.ui.control.NumericEditField
        D0Label_11                     matlab.ui.control.Label
        HomomorphicModeDropDown_5      matlab.ui.control.DropDown
        PaddingLabel_9                 matlab.ui.control.Label
        ButterworthPanel               matlab.ui.container.Panel
        HomomorphicAmountEditField_19  matlab.ui.control.NumericEditField
        D0Label_26                     matlab.ui.control.Label
        HomomorphicAmountEditField_17  matlab.ui.control.NumericEditField
        D0Label_24                     matlab.ui.control.Label
        HomomorphicAmountEditField_16  matlab.ui.control.NumericEditField
        D0Label_23                     matlab.ui.control.Label
        HomomorphicModeDropDown_8      matlab.ui.control.DropDown
        PaddingLabel_12                matlab.ui.control.Label
        BHPFPanel                      matlab.ui.container.Panel
        BLPFAmountEditField_4          matlab.ui.control.NumericEditField
        D0Label_34                     matlab.ui.control.Label
        BLPFModeDropDown_2             matlab.ui.control.DropDown
        PaddingLabel_16                matlab.ui.control.Label
        BLPFAmountEditField_3          matlab.ui.control.NumericEditField
        D0Label_33                     matlab.ui.control.Label
        BLPFPanel                      matlab.ui.container.Panel
        BLPFAmountEditField            matlab.ui.control.NumericEditField
        D0Label_6                      matlab.ui.control.Label
        BLPFModeDropDown               matlab.ui.control.DropDown
        PaddingLabel_4                 matlab.ui.control.Label
        BLPFAmountEditField_2          matlab.ui.control.NumericEditField
        D0Label_5                      matlab.ui.control.Label
        GaussianPanel                  matlab.ui.container.Panel
        HomomorphicAmountEditField_22  matlab.ui.control.NumericEditField
        D0Label_29                     matlab.ui.control.Label
        HomomorphicAmountEditField_20  matlab.ui.control.NumericEditField
        D0Label_27                     matlab.ui.control.Label
        HomomorphicModeDropDown_9      matlab.ui.control.DropDown
        PaddingLabel_13                matlab.ui.control.Label
        GLPFPanel                      matlab.ui.container.Panel
        GLPFModeDropDown               matlab.ui.control.DropDown
        PaddingLabel_3                 matlab.ui.control.Label
        GLPFAmountEditField            matlab.ui.control.NumericEditField
        D0Label_4                      matlab.ui.control.Label
        ILPFPanel_2                    matlab.ui.container.Panel
        ILPFModeDropDown               matlab.ui.control.DropDown
        PaddingLabel_2                 matlab.ui.control.Label
        ILPFAmountEditField            matlab.ui.control.NumericEditField
        D0Label_3                      matlab.ui.control.Label
        GHPFPanel                      matlab.ui.container.Panel
        IHPFModeDropDown_4             matlab.ui.control.DropDown
        PaddingLabel_8                 matlab.ui.control.Label
        IHPFAmountEditField_2          matlab.ui.control.NumericEditField
        D0Label_9                      matlab.ui.control.Label
        IHPFModeDropDown_3             matlab.ui.control.DropDown
        PaddingLabel_7                 matlab.ui.control.Label
        IdealPanel                     matlab.ui.container.Panel
        HomomorphicAmountEditField_15  matlab.ui.control.NumericEditField
        D0Label_22                     matlab.ui.control.Label
        HomomorphicAmountEditField_12  matlab.ui.control.NumericEditField
        D0Label_19                     matlab.ui.control.Label
        HomomorphicModeDropDown_7      matlab.ui.control.DropDown
        PaddingLabel_11                matlab.ui.control.Label
        PSFPanel                       matlab.ui.container.Panel
        PSFLEditField                  matlab.ui.control.NumericEditField
        LLabel                         matlab.ui.control.Label
        PSFThetaEditField              matlab.ui.control.NumericEditField
        Label_2                        matlab.ui.control.Label
        WienerPanel                    matlab.ui.container.Panel
        KEditField                     matlab.ui.control.NumericEditField
        LLabel_3                       matlab.ui.control.Label
        WienerLEditField               matlab.ui.control.NumericEditField
        LLabel_2                       matlab.ui.control.Label
        WienerThetaEditField           matlab.ui.control.NumericEditField
        Label_3                        matlab.ui.control.Label
    end

    
    properties (Access = private)
        OriginalImage = []
        CurrentImage = []
        CurrentInfo = []
        CurrentParams = struct()
        History = {}
        ParamPanels = struct()
        ImageName = ""
        ReferenceImage = []
    end
    
    methods (Access = private)

        function showParamsPanel(app)
            method = app.MethodsButtonGroup.SelectedObject.Tag;
            panels = struct2cell(app.ParamPanels);
            set([panels{:}], 'Visible', 'off');
            app.ParamPanels.(method).Visible = 'on';
        end

        function params = readParams(app, method)
            pad = @(dd) lower(string(dd.Value));

            switch method
                case 'ILPF'
                    params = struct('padding', pad(app.ILPFModeDropDown), ...
                        'D0', app.ILPFAmountEditField.Value);
                case 'GLPF'
                    params = struct('padding', pad(app.GLPFModeDropDown), ...
                        'sigma', app.GLPFAmountEditField.Value);
                case 'BLPF'
                    params = struct('padding', pad(app.BLPFModeDropDown), ...
                        'D0', app.BLPFAmountEditField_2.Value, 'order', app.BLPFAmountEditField.Value);
                case 'IHPF'
                    params = struct('padding', pad(app.IHPFModeDropDown), ...
                        'outputMode', pad(app.IHPFModeDropDown_2), 'D0', app.IHPFAmountEditField.Value);
                case 'GHPF'
                    params = struct('padding', pad(app.IHPFModeDropDown_3), ...
                        'outputMode', pad(app.IHPFModeDropDown_4), 'sigma', app.IHPFAmountEditField_2.Value);
                case 'BHPF'
                    params = struct('padding', pad(app.BLPFModeDropDown_2), ...
                        'outputMode', "clip", ...
                        'D0', app.BLPFAmountEditField_3.Value, 'order', app.BLPFAmountEditField_4.Value);
                case 'homomorphic'
                    params = struct('padding', pad(app.HomomorphicModeDropDown_5), ...
                        'D0', app.HomomorphicAmountEditField_7.Value, ...
                        'gammaL', app.HomomorphicAmountEditField_4.Value, ...
                        'gammaH', app.HomomorphicAmountEditField_5.Value, ...
                        'c', app.HomomorphicAmountEditField_6.Value);
                case 'dc'
                    params = struct('gain', app.HomomorphicAmountEditField_28.Value, ...
                        'offset', app.HomomorphicAmountEditField_29.Value);
                case 'IBRF'
                    params = struct('padding', pad(app.HomomorphicModeDropDown_7), ...
                        'D0', app.HomomorphicAmountEditField_15.Value, ...
                        'W', app.HomomorphicAmountEditField_12.Value);
                case 'BBRF'
                    params = struct('padding', pad(app.HomomorphicModeDropDown_8), ...
                        'D0', app.HomomorphicAmountEditField_19.Value, ...
                        'W', app.HomomorphicAmountEditField_16.Value, ...
                        'order', app.HomomorphicAmountEditField_17.Value);
                case 'GBRF'
                    params = struct('padding', pad(app.HomomorphicModeDropDown_9), ...
                        'D0', app.HomomorphicAmountEditField_22.Value, ...
                        'W', app.HomomorphicAmountEditField_20.Value);
                case 'notch'
                    params = struct('padding', pad(app.HomomorphicModeDropDown_10), ...
                        'notchType', pad(app.HomomorphicModeDropDown_11), ...
                        'k', app.HomomorphicAmountEditField_23.Value, ...
                        'rDC', app.HomomorphicAmountEditField_30.Value, ...
                        'D0', app.HomomorphicAmountEditField_24.Value);
                    if params.notchType == "butterworth"
                        params.order = app.HomomorphicAmountEditField_25.Value;
                    end
                case 'psf'
                    params = struct('L', app.PSFLEditField.Value, ...
                        'angle', app.PSFThetaEditField.Value);
                case 'inverse'
                    params = struct('L', app.InverseLEditField.Value, ...
                        'angle', app.InverseThetaEditField.Value);
                    params.mode = pad(app.HomomorphicModeDropDown_6);
                    tiny = 1e-6;
                    switch params.mode
                        case "none"
                            params.threshold = tiny;
                        case "threshold"
                            params.threshold = app.HomomorphicAmountEditField_11.Value;
                        case "radius"
                            params.threshold = tiny;
                            params.radius = app.HomomorphicAmountEditField_8.Value;
                    end
                case 'wiener'
                    params = struct('L', app.WienerLEditField.Value, ...
                        'angle', app.WienerThetaEditField.Value, ...
                        'K', app.KEditField.Value);
            end
        end

        function [outImg, info, params] = runMethod(app, method, params)
            img = app.OriginalImage;
            switch method
                case {'ILPF', 'GLPF', 'BLPF', 'IHPF', 'GHPF', 'BHPF'}
                    [outImg, info] = frequencyFilterImage(img, method, params);
                case {'homomorphic', 'dc'}
                    [outImg, info] = brightenImage(img, method, params);
                case {'IBRF', 'BBRF', 'GBRF'}
                    [outImg, info] = periodicRestoreImage(img, method, params);
                case 'notch'
                    centers = parseCenters(app);
                    if isempty(centers)
                        centers = detectSpikes(img, params.k, params.rDC, params.padding);
                        if isempty(centers)
                            error("main:noSpikes", "No spike rises above the threshold. Lower k or rDC.");
                        end
                        fillCenters(app, centers);
                    end
                    params.centers = centers;
                    [outImg, info] = periodicRestoreImage(img, 'notch', params);
                case 'psf'
                    [outImg, info] = motionBlurImage(img, params);
                case {'inverse', 'wiener'}
                    [outImg, info] = motionRestoreImage(img, method, params);
            end
        end

        function [img, fileName] = pickImage(app, dialogTitle)
            img = [];
            [fileName, folder] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp;*.tif', 'Image Files'}, dialogTitle);
            figure(app.UIFigure);
            if isequal(fileName, 0)
                return;
            end
            img = loadImage(fullfile(folder, fileName));
        end

        function readPSFFromName(app)
            tok = regexp(app.ImageName, '_L(\d+(?:\.\d+)?)_A(-?\d+(?:\.\d+)?)', 'tokens', 'once');
            if ~isempty(tok)
                set([app.PSFLEditField, app.InverseLEditField, app.WienerLEditField], ...
                    'Value', str2double(tok{1}));
                set([app.PSFThetaEditField, app.InverseThetaEditField, app.WienerThetaEditField], ...
                    'Value', str2double(tok{2}));
            end
        end

        function showImage(~, imageComp, img)
            if size(img, 3) == 1
                imageComp.ImageSource = repmat(img, 1, 1, 3);
            else
                imageComp.ImageSource = img;
            end
        end

        function showSpectra(~, slots, labels, spec, channels, marks)
            for k = 1:numel(slots)
                visible = k <= size(spec, 3);
                slots(k).Visible = visible;
                labels(k).Visible = visible;
                if visible
                    rgb = toDisplayImage(spec(:, :, k));
                    if ~isempty(marks)
                        rgb = drawCircles(rgb, marks.points, marks.radius);
                    end
                    slots(k).ImageSource = rgb;
                    labels(k).Text = channels(k);
                end
            end
        end

        function marks = notchMarks(app)
            marks = [];
            if isempty(app.CurrentInfo) || ~isfield(app.CurrentParams, 'centers')
                return;
            end
            sz = app.CurrentInfo.spectrumSize;
            centre = floor(sz / 2) + 1;
            c = app.CurrentParams.centers;
            marks = struct('points', [centre + c; centre - c], ...
                'radius', max(app.CurrentParams.D0, min(sz) / 60));
        end

        function centers = parseCenters(app)
            txt = strjoin(string(app.NotchCentersTextArea.Value), " ");
            nums = sscanf(regexprep(char(txt), '[(),;]', ' '), '%f');
            if mod(numel(nums), 2) ~= 0
                error("main:badCenters", "Each centre needs two values: dRow, dCol.");
            end
            centers = reshape(nums, 2, []).';
        end

        function fillCenters(app, centers)
            app.NotchCentersTextArea.Value = cellstr(compose("%d, %d", centers));
        end

        function pickSpike(app, ax, S, point)
            [P, Q] = size(S);
            r0 = floor(P/2) + 1;
            c0 = floor(Q/2) + 1;
            r = round(point(2)) + r0;
            c = round(point(1)) + c0;
            rr = max(r - 3, 1):min(r + 3, P);
            cc = max(c - 3, 1):min(c + 3, Q);
            [~, idx] = max(reshape(S(rr, cc), [], 1));
            [ir, ic] = ind2sub([numel(rr), numel(cc)], idx);
            d = [rr(ir) - r0, cc(ic) - c0];
            if d(1) < 0 || (d(1) == 0 && d(2) < 0)
                d = -d;
            end
            title(ax, sprintf('(\\Delta row, \\Delta col) = (%d, %d),  D = %.1f', d, hypot(d(1), d(2))));

            if strcmp(app.MethodsButtonGroup.SelectedObject.Tag, 'notch')
                centers = parseCenters(app);
                hit = ismember(centers, d, 'rows');
                if any(hit)
                    centers(hit, :) = [];
                else
                    centers = [centers; d];
                end
                if isempty(centers)
                    app.NotchCentersTextArea.Value = {''};
                else
                    fillCenters(app, centers);
                end
                marks = centers;
            else
                marks = d;
            end
            drawSpikeMarks(app, ax, marks);
        end

        function drawSpikeMarks(~, ax, marks)
            delete(findobj(ax, 'Tag', 'spikeMark'));
            if ~isempty(marks)
                plot(ax, [marks(:, 2); -marks(:, 2)], [marks(:, 1); -marks(:, 1)], 'mo', ...
                    'MarkerSize', 12, 'LineWidth', 1.5, 'Tag', 'spikeMark', 'PickableParts', 'none');
            end
        end

        function saveSpectrum(~, spec, marks, file)
            tiles = cell(1, size(spec, 3));
            for k = 1:size(spec, 3)
                rgb = toDisplayImage(spec(:, :, k));
                if ~isempty(marks)
                    rgb = drawCircles(rgb, marks.points, marks.radius);
                end
                tiles{k} = rgb;
            end
            imwrite(cat(2, tiles{:}), file);
        end

        function updateInverseModeFields(app)
            mode = app.HomomorphicModeDropDown_6.Value;
            set([app.D0Label_18, app.HomomorphicAmountEditField_11], 'Visible', strcmp(mode, 'Threshold'));
            set([app.D0Label_15, app.HomomorphicAmountEditField_8], 'Visible', strcmp(mode, 'Radius'));
        end

        function updateNotchOrderField(app)
            isButterworth = strcmp(app.HomomorphicModeDropDown_11.Value, 'Butterworth');
            set([app.D0Label_32, app.HomomorphicAmountEditField_25], 'Visible', isButterworth);
        end

        function items = qualityItems(app, img)
            items = {};
            if isempty(app.ReferenceImage)
                return;
            end
            if ~isequal(size(img), size(app.ReferenceImage))
                items = {'PSNR: size differs from reference'};
                return;
            end
            items = {sprintf('MSE vs ref: %.2f', computeMSE(img, app.ReferenceImage)), ...
                sprintf('PSNR vs ref: %.2f dB', computePSNR(img, app.ReferenceImage))};
        end

        function items = imageItems(~, title, img)
            gray = double(toGrayscale(img));
            if size(img, 3) == 3
                kind = 'RGB';
            else
                kind = 'Grayscale';
            end
            items = {title, ...
                sprintf('Size: %d x %d (%s)', size(img, 1), size(img, 2), kind), ...
                sprintf('Mean: %.2f', mean(gray(:))), ...
                sprintf('Min / Max: %d / %d', min(gray(:)), max(gray(:)))};
        end

        function name = methodName(app)
            name = char(extractBefore(app.CurrentInfo.label + " (", " ("));
        end

        function items = paramItems(app)
            info = app.CurrentInfo;
            params = app.CurrentParams;
            items = {'Parameters', methodName(app)};

            names = fieldnames(params);
            for k = 1:numel(names)
                value = params.(names{k});
                if strcmp(names{k}, 'centers')
                    items{end+1} = sprintf('notch centres (dRow, dCol): %d pairs', size(value, 1));
                    for r = 1:size(value, 1)
                        items{end+1} = sprintf('   (%d, %d) and (%d, %d)', value(r, :), -value(r, :));
                    end
                else
                    items{end+1} = sprintf('%s = %s', names{k}, string(value));
                end
            end

            items{end+1} = sprintf('Spectrum size: %d x %d', info.spectrumSize);
            Hmag = abs(info.H);
            items{end+1} = sprintf('|H| range: [%.4g, %.4g]', min(Hmag(:)), max(Hmag(:)));
        end

        function filterImg = filterDisplay(app)
            info = app.CurrentInfo;
            if isfield(info, 'psf')
                filterImg = resizeNearest(toDisplayImage(info.psf), 158);
            else
                filterImg = toDisplayImage(abs(info.H));
            end
        end

        function refreshAll(app)
            inputSlots = [app.RInputImage, app.GInputImage, app.BInputImage];
            inputLabels = [app.RLabel, app.GLabel, app.BLabel];
            refSlots = [app.RReferenceImage, app.GReferenceImage, app.BReferenceImage];
            refLabels = [app.RLabel_3, app.GLabel_3, app.BLabel_3];
            resultSlots = [app.RResultImage, app.GResultImage, app.BResultImage];
            resultLabels = [app.RLabel_2, app.GLabel_2, app.BLabel_2];

            if isempty(app.ReferenceImage)
                app.BeforeImage_14.ImageSource = '';
                set([refSlots, refLabels], 'Visible', 'off');
                app.BeforeStats_5.Items = {'Reference', 'No reference image.'};
            else
                ref = app.ReferenceImage;
                showImage(app, app.BeforeImage_14, ref);
                showSpectra(app, refSlots, refLabels, computeSpectra(ref), channelNames(size(ref, 3)), []);
                app.BeforeStats_5.Items = imageItems(app, 'Reference', ref);
            end

            if isempty(app.OriginalImage)
                updateButtonStates(app);
                return;
            end

            showImage(app, app.BeforeImage, app.OriginalImage);
            app.BeforeStats.Items = [imageItems(app, 'Input', app.OriginalImage), ...
                qualityItems(app, app.OriginalImage)];

            if isempty(app.CurrentInfo)
                C = size(app.OriginalImage, 3);
                showSpectra(app, inputSlots, inputLabels, computeSpectra(app.OriginalImage), channelNames(C), []);
                app.BeforeImage_10.ImageSource = '';
                set([resultSlots, resultLabels], 'Visible', 'off');
                app.BeforeImage_9.ImageSource = '';
                app.BeforeStats_4.Items = {'Result', 'No method applied yet.'};
                app.BeforeStats_3.Items = {'Parameters', 'No method applied yet.'};
                app.ResultImagePanel.Title = 'Result Image';
            else
                info = app.CurrentInfo;
                showSpectra(app, inputSlots, inputLabels, info.spectrumIn, info.channels, notchMarks(app));
                showImage(app, app.BeforeImage_10, app.CurrentImage);
                showSpectra(app, resultSlots, resultLabels, info.spectrumOut, info.channels, []);
                app.BeforeImage_9.ImageSource = filterDisplay(app);
                app.BeforeStats_4.Items = [imageItems(app, 'Result', app.CurrentImage), ...
                    qualityItems(app, app.CurrentImage)];
                app.BeforeStats_3.Items = paramItems(app);
                app.ResultImagePanel.Title = "Result Image - " + methodName(app);
            end

            updateButtonStates(app);
        end

        function updateButtonStates(app)
            hasImage = ~isempty(app.OriginalImage);
            hasResult = ~isempty(app.CurrentImage);
            app.ApplyButton.Enable = hasImage;
            app.NotchDetectButton.Enable = hasImage;
            app.InspectSpectrumButton.Enable = hasImage;
            app.UndoButton.Enable = ~isempty(app.History);
            app.ResetButton.Enable = hasResult;
            app.SaveButton.Enable = hasResult;
        end

        function resetResult(app)
            app.CurrentImage = [];
            app.CurrentInfo = [];
            app.CurrentParams = struct();
            app.History = {};
            refreshAll(app);
        end

        function highlightSelectedMethod(app)
            ACTIVE_BG = [1 0.1412 0.5216];
            ACTIVE_FG = [1 1 1];
            IDLE_BG   = [0.9765 0.8706 0.898];
            IDLE_FG   = [0 0.1765 0.1216];

            buttons = findobj(app.MethodsButtonGroup, 'Type', 'uitogglebutton');
            set(buttons, 'BackgroundColor', IDLE_BG, 'FontColor', IDLE_FG);
            set(app.MethodsButtonGroup.SelectedObject, 'BackgroundColor', ACTIVE_BG, 'FontColor', ACTIVE_FG);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath(fullfile(fileparts(mfilename('fullpath')), 'src')));

            app.ILPFButton.Tag = 'ILPF';
            app.GLPFButton.Tag = 'GLPF';
            app.BLPFButton.Tag = 'BLPF';
            app.IHPFButton.Tag = 'IHPF';
            app.GHPFButton.Tag = 'GHPF';
            app.BHPFButton.Tag = 'BHPF';
            app.HomomorphicButton.Tag = 'homomorphic';
            app.DCButton.Tag = 'dc';
            app.IdealButton.Tag = 'IBRF';
            app.ButterworthButton.Tag = 'BBRF';
            app.GaussianButton.Tag = 'GBRF';
            app.NotchButton.Tag = 'notch';
            app.PSFButton.Tag = 'psf';
            app.InverseButton.Tag = 'inverse';
            app.WienerButton.Tag = 'wiener';

            app.ParamPanels = struct( ...
                'ILPF',        app.ILPFPanel_2, ...
                'GLPF',        app.GLPFPanel, ...
                'BLPF',        app.BLPFPanel, ...
                'IHPF',        app.IHPFPanel, ...
                'GHPF',        app.GHPFPanel, ...
                'BHPF',        app.BHPFPanel, ...
                'homomorphic', app.HomomorphicPanel, ...
                'dc',          app.DCPanel, ...
                'IBRF',        app.IdealPanel, ...
                'BBRF',        app.ButterworthPanel, ...
                'GBRF',        app.GaussianPanel, ...
                'notch',       app.NotchPanel, ...
                'psf',         app.PSFPanel, ...
                'inverse',     app.InversePanel, ...
                'wiener',      app.WienerPanel);

            set([app.RInputImage, app.GInputImage, app.BInputImage, ...
                 app.RReferenceImage, app.GReferenceImage, app.BReferenceImage, ...
                 app.RResultImage, app.GResultImage, app.BResultImage, ...
                 app.RLabel, app.GLabel, app.BLabel, ...
                 app.RLabel_3, app.GLabel_3, app.BLabel_3, ...
                 app.RLabel_2, app.GLabel_2, app.BLabel_2], 'Visible', 'off');

            showParamsPanel(app);
            updateButtonStates(app);
            updateNotchOrderField(app);
            updateInverseModeFields(app);
            highlightSelectedMethod(app);
        end

        % Selection changed function: MethodsButtonGroup
        function MethodsButtonGroupSelectionChanged(app, event)
            showParamsPanel(app);         
            highlightSelectedMethod(app);
        end

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            [img, fileName] = pickImage(app, 'Choose Image');
            if isempty(img)
                return;
            end

            app.OriginalImage = img;
            [~, name] = fileparts(fileName);
            app.ImageName = string(name);
            readPSFFromName(app);
            app.NotchCentersTextArea.Value = {''};
            resetResult(app);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            method = app.MethodsButtonGroup.SelectedObject.Tag;
            try
                [outImg, info, params] = runMethod(app, method, readParams(app, method));
            catch err
                uialert(app.UIFigure, err.message, 'Cannot Apply Method');
                return;
            end

            app.History{end+1} = {app.CurrentImage, app.CurrentInfo, app.CurrentParams};
            app.CurrentImage = outImg;
            app.CurrentInfo = info;
            app.CurrentParams = params;
            refreshAll(app);
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            [app.CurrentImage, app.CurrentInfo, app.CurrentParams] = app.History{end}{:};
            app.History(end) = [];
            refreshAll(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            resetResult(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            defaultName = char(app.ImageName + "_" + app.MethodsButtonGroup.SelectedObject.Tag + ".png");
            [fileName, folder] = uiputfile({'*.png'; '*.jpg'; '*.bmp'}, 'Save Result', defaultName);
            figure(app.UIFigure);
            if isequal(fileName, 0)
                return;
            end
            imwrite(app.CurrentImage, fullfile(folder, fileName));

            [~, baseName] = fileparts(fileName);
            out = @(suffix) fullfile(folder, [baseName suffix]);
            info = app.CurrentInfo;
            saveSpectrum(app, info.spectrumIn, notchMarks(app), out('_spectrum_before.png'));
            saveSpectrum(app, info.spectrumOut, [], out('_spectrum_after.png'));
            imwrite(filterDisplay(app), out('_filter.png'));
            writelines(string(paramItems(app)), out('_params.txt'));
        end

        % Button pushed function: NotchDetectButton
        function NotchDetectButtonPushed(app, event)
            if isempty(app.OriginalImage)
                return;
            end
            params = readParams(app, 'notch');
            centers = detectSpikes(app.OriginalImage, params.k, params.rDC, params.padding);
            if isempty(centers)
                uialert(app.UIFigure, "No spike rises above the threshold. Lower k or rDC.", 'Detect Spikes');
                return;
            end
            fillCenters(app, centers);
        end

        % Button pushed function: InspectSpectrumButton
        function InspectSpectrumButtonPushed(app, event)
            if isempty(app.OriginalImage)
                return;
            end
            method = app.MethodsButtonGroup.SelectedObject.Tag;
            params = readParams(app, method);
            padding = "none";
            if isfield(params, 'padding')
                padding = params.padding;
            end

            f = double(toGrayscale(app.OriginalImage));
            if padding ~= "none"
                f = padImage(f, padding);
            end
            S = logMagnitude(centeredSpectrum(f));
            [P, Q] = size(S);

            fig = figure('Name', 'Spectrum (click a spike)', 'NumberTitle', 'off');
            ax = axes(fig);
            im = imagesc(ax, (1:Q) - (floor(Q/2) + 1), (1:P) - (floor(P/2) + 1), S);
            colormap(ax, gray);
            axis(ax, 'image');
            xlabel(ax, '\Delta col');
            ylabel(ax, '\Delta row');
            title(ax, 'Click a spike to read its position');
            hold(ax, 'on');

            if strcmp(method, 'notch')
                c = parseCenters(app);
                if ~isempty(c)
                    plot(ax, [c(:, 2); -c(:, 2)], [c(:, 1); -c(:, 1)], 'mo', 'MarkerSize', 12, 'LineWidth', 1.5);
                end
            end
            im.ButtonDownFcn = @(~, evt) pickSpike(app, ax, S, evt.IntersectionPoint);
        end

        % Button pushed function: UploadImageButton_2
        function UploadImageButton_2Pushed(app, event)
            img = pickImage(app, 'Choose Reference Image');
            if isempty(img)
                return;
            end
            app.ReferenceImage = img;
            refreshAll(app);
        end

        % Value changed function: HomomorphicModeDropDown_11
        function HomomorphicModeDropDown_11ValueChanged(app, event)
            updateNotchOrderField(app);
        end

        % Value changed function: HomomorphicModeDropDown_6
        function HomomorphicModeDropDown_6ValueChanged(app, event)
            updateInverseModeFields(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'ParaPix';
            app.WienerPanel = uipanel(app.UIFigure);
            app.WienerPanel.Title = 'Wiener';
            app.Label_3 = uilabel(app.WienerPanel);
            app.Label_3.Text = 'θ';
            app.WienerThetaEditField = uieditfield(app.WienerPanel, 'numeric');
            app.WienerThetaEditField.Limits = [-180 180];
            app.LLabel_2 = uilabel(app.WienerPanel);
            app.LLabel_2.Text = 'L';
            app.WienerLEditField = uieditfield(app.WienerPanel, 'numeric');
            app.WienerLEditField.Limits = [1 Inf];
            app.WienerLEditField.Value = 10;
            app.LLabel_3 = uilabel(app.WienerPanel);
            app.LLabel_3.Text = 'K';
            app.KEditField = uieditfield(app.WienerPanel, 'numeric');
            app.KEditField.Limits = [0 Inf];
            app.KEditField.Value = 0.01;
            app.PSFPanel = uipanel(app.UIFigure);
            app.PSFPanel.Title = 'PSF';
            app.Label_2 = uilabel(app.PSFPanel);
            app.Label_2.Text = 'θ';
            app.PSFThetaEditField = uieditfield(app.PSFPanel, 'numeric');
            app.PSFThetaEditField.Limits = [-180 180];
            app.LLabel = uilabel(app.PSFPanel);
            app.LLabel.Text = 'L';
            app.PSFLEditField = uieditfield(app.PSFPanel, 'numeric');
            app.PSFLEditField.Limits = [1 Inf];
            app.PSFLEditField.Value = 10;
            app.IdealPanel = uipanel(app.UIFigure);
            app.IdealPanel.Title = 'Ideal';
            app.PaddingLabel_11 = uilabel(app.IdealPanel);
            app.PaddingLabel_11.Text = 'Padding';
            app.HomomorphicModeDropDown_7 = uidropdown(app.IdealPanel);
            app.HomomorphicModeDropDown_7.Items = {'None', 'Zero', 'Mirror'};
            app.HomomorphicModeDropDown_7.Value = 'None';
            app.D0Label_19 = uilabel(app.IdealPanel);
            app.D0Label_19.Text = 'W';
            app.HomomorphicAmountEditField_12 = uieditfield(app.IdealPanel, 'numeric');
            app.HomomorphicAmountEditField_12.Limits = [0 Inf];
            app.HomomorphicAmountEditField_12.Value = 10;
            app.D0Label_22 = uilabel(app.IdealPanel);
            app.D0Label_22.Text = 'C0';
            app.HomomorphicAmountEditField_15 = uieditfield(app.IdealPanel, 'numeric');
            app.HomomorphicAmountEditField_15.Limits = [0 Inf];
            app.HomomorphicAmountEditField_15.Value = 50;
            app.GHPFPanel = uipanel(app.UIFigure);
            app.GHPFPanel.Title = 'GHPF';
            app.PaddingLabel_7 = uilabel(app.GHPFPanel);
            app.PaddingLabel_7.Text = 'Padding';
            app.IHPFModeDropDown_3 = uidropdown(app.GHPFPanel);
            app.IHPFModeDropDown_3.Items = {'None', 'Zero', 'Mirror'};
            app.IHPFModeDropDown_3.Value = 'None';
            app.D0Label_9 = uilabel(app.GHPFPanel);
            app.D0Label_9.Text = 'σ';
            app.IHPFAmountEditField_2 = uieditfield(app.GHPFPanel, 'numeric');
            app.IHPFAmountEditField_2.Limits = [0 Inf];
            app.IHPFAmountEditField_2.Value = 30;
            app.PaddingLabel_8 = uilabel(app.GHPFPanel);
            app.PaddingLabel_8.Text = 'outputMode';
            app.IHPFModeDropDown_4 = uidropdown(app.GHPFPanel);
            app.IHPFModeDropDown_4.Items = {'Clip', 'Normalize'};
            app.IHPFModeDropDown_4.Value = 'Clip';
            app.ILPFPanel_2 = uipanel(app.UIFigure);
            app.ILPFPanel_2.Title = 'ILPF';
            app.D0Label_3 = uilabel(app.ILPFPanel_2);
            app.D0Label_3.Text = 'D0';
            app.ILPFAmountEditField = uieditfield(app.ILPFPanel_2, 'numeric');
            app.ILPFAmountEditField.Limits = [0 Inf];
            app.ILPFAmountEditField.Value = 40;
            app.PaddingLabel_2 = uilabel(app.ILPFPanel_2);
            app.PaddingLabel_2.Text = 'Padding';
            app.ILPFModeDropDown = uidropdown(app.ILPFPanel_2);
            app.ILPFModeDropDown.Items = {'None', 'Zero', 'Mirror'};
            app.ILPFModeDropDown.Value = 'None';
            app.GLPFPanel = uipanel(app.UIFigure);
            app.GLPFPanel.Title = 'GLPF';
            app.D0Label_4 = uilabel(app.GLPFPanel);
            app.D0Label_4.Text = 'σ';
            app.GLPFAmountEditField = uieditfield(app.GLPFPanel, 'numeric');
            app.GLPFAmountEditField.Limits = [0 Inf];
            app.GLPFAmountEditField.Value = 40;
            app.PaddingLabel_3 = uilabel(app.GLPFPanel);
            app.PaddingLabel_3.Text = 'Padding';
            app.GLPFModeDropDown = uidropdown(app.GLPFPanel);
            app.GLPFModeDropDown.Items = {'None', 'Zero', 'Mirror'};
            app.GLPFModeDropDown.Value = 'None';
            app.GaussianPanel = uipanel(app.UIFigure);
            app.GaussianPanel.Title = 'Gaussian';
            app.PaddingLabel_13 = uilabel(app.GaussianPanel);
            app.PaddingLabel_13.Text = 'Padding';
            app.HomomorphicModeDropDown_9 = uidropdown(app.GaussianPanel);
            app.HomomorphicModeDropDown_9.Items = {'None', 'Zero', 'Mirror'};
            app.HomomorphicModeDropDown_9.Value = 'None';
            app.D0Label_27 = uilabel(app.GaussianPanel);
            app.D0Label_27.Text = 'W';
            app.HomomorphicAmountEditField_20 = uieditfield(app.GaussianPanel, 'numeric');
            app.HomomorphicAmountEditField_20.Limits = [0 Inf];
            app.HomomorphicAmountEditField_20.Value = 10;
            app.D0Label_29 = uilabel(app.GaussianPanel);
            app.D0Label_29.Text = 'C0';
            app.HomomorphicAmountEditField_22 = uieditfield(app.GaussianPanel, 'numeric');
            app.HomomorphicAmountEditField_22.Limits = [0 Inf];
            app.HomomorphicAmountEditField_22.Value = 50;
            app.BLPFPanel = uipanel(app.UIFigure);
            app.BLPFPanel.Title = 'BLPF';
            app.D0Label_5 = uilabel(app.BLPFPanel);
            app.D0Label_5.Text = 'D0';
            app.BLPFAmountEditField_2 = uieditfield(app.BLPFPanel, 'numeric');
            app.BLPFAmountEditField_2.Limits = [0 Inf];
            app.BLPFAmountEditField_2.Value = 40;
            app.PaddingLabel_4 = uilabel(app.BLPFPanel);
            app.PaddingLabel_4.Text = 'Padding';
            app.BLPFModeDropDown = uidropdown(app.BLPFPanel);
            app.BLPFModeDropDown.Items = {'None', 'Zero', 'Mirror'};
            app.BLPFModeDropDown.Value = 'None';
            app.D0Label_6 = uilabel(app.BLPFPanel);
            app.D0Label_6.Text = 'n';
            app.BLPFAmountEditField = uieditfield(app.BLPFPanel, 'numeric');
            app.BLPFAmountEditField.Limits = [0 Inf];
            app.BLPFAmountEditField.Value = 2;
            app.BHPFPanel = uipanel(app.UIFigure);
            app.BHPFPanel.Title = 'BHPF';
            app.D0Label_33 = uilabel(app.BHPFPanel);
            app.D0Label_33.Text = 'D0';
            app.BLPFAmountEditField_3 = uieditfield(app.BHPFPanel, 'numeric');
            app.BLPFAmountEditField_3.Limits = [0 Inf];
            app.BLPFAmountEditField_3.Value = 30;
            app.PaddingLabel_16 = uilabel(app.BHPFPanel);
            app.PaddingLabel_16.Text = 'Padding';
            app.BLPFModeDropDown_2 = uidropdown(app.BHPFPanel);
            app.BLPFModeDropDown_2.Items = {'None', 'Zero', 'Mirror'};
            app.BLPFModeDropDown_2.Value = 'None';
            app.D0Label_34 = uilabel(app.BHPFPanel);
            app.D0Label_34.Text = 'n';
            app.BLPFAmountEditField_4 = uieditfield(app.BHPFPanel, 'numeric');
            app.BLPFAmountEditField_4.Limits = [0 Inf];
            app.BLPFAmountEditField_4.Value = 2;
            app.ButterworthPanel = uipanel(app.UIFigure);
            app.ButterworthPanel.Title = 'Butterworth';
            app.PaddingLabel_12 = uilabel(app.ButterworthPanel);
            app.PaddingLabel_12.Text = 'Padding';
            app.HomomorphicModeDropDown_8 = uidropdown(app.ButterworthPanel);
            app.HomomorphicModeDropDown_8.Items = {'None', 'Zero', 'Mirror'};
            app.HomomorphicModeDropDown_8.Value = 'None';
            app.D0Label_23 = uilabel(app.ButterworthPanel);
            app.D0Label_23.Text = 'W';
            app.HomomorphicAmountEditField_16 = uieditfield(app.ButterworthPanel, 'numeric');
            app.HomomorphicAmountEditField_16.Limits = [0 Inf];
            app.HomomorphicAmountEditField_16.Value = 10;
            app.D0Label_24 = uilabel(app.ButterworthPanel);
            app.D0Label_24.Text = 'n';
            app.HomomorphicAmountEditField_17 = uieditfield(app.ButterworthPanel, 'numeric');
            app.HomomorphicAmountEditField_17.Limits = [0 Inf];
            app.HomomorphicAmountEditField_17.Value = 2;
            app.D0Label_26 = uilabel(app.ButterworthPanel);
            app.D0Label_26.Text = 'C0';
            app.HomomorphicAmountEditField_19 = uieditfield(app.ButterworthPanel, 'numeric');
            app.HomomorphicAmountEditField_19.Limits = [0 Inf];
            app.HomomorphicAmountEditField_19.Value = 50;
            app.HomomorphicPanel = uipanel(app.UIFigure);
            app.HomomorphicPanel.Title = 'Homomorphic';
            app.PaddingLabel_9 = uilabel(app.HomomorphicPanel);
            app.PaddingLabel_9.Text = 'Padding';
            app.HomomorphicModeDropDown_5 = uidropdown(app.HomomorphicPanel);
            app.HomomorphicModeDropDown_5.Items = {'None', 'Zero', 'Mirror'};
            app.HomomorphicModeDropDown_5.Value = 'None';
            app.D0Label_11 = uilabel(app.HomomorphicPanel);
            app.D0Label_11.Text = 'γL';
            app.HomomorphicAmountEditField_4 = uieditfield(app.HomomorphicPanel, 'numeric');
            app.HomomorphicAmountEditField_4.Limits = [0 Inf];
            app.HomomorphicAmountEditField_4.Value = 0.5;
            app.D0Label_12 = uilabel(app.HomomorphicPanel);
            app.D0Label_12.Text = 'γH';
            app.HomomorphicAmountEditField_5 = uieditfield(app.HomomorphicPanel, 'numeric');
            app.HomomorphicAmountEditField_5.Limits = [0 Inf];
            app.HomomorphicAmountEditField_5.Value = 1.2;
            app.D0Label_13 = uilabel(app.HomomorphicPanel);
            app.D0Label_13.Text = 'c';
            app.HomomorphicAmountEditField_6 = uieditfield(app.HomomorphicPanel, 'numeric');
            app.HomomorphicAmountEditField_6.Limits = [0 Inf];
            app.HomomorphicAmountEditField_6.Value = 1;
            app.D0Label_14 = uilabel(app.HomomorphicPanel);
            app.D0Label_14.Text = 'D0';
            app.HomomorphicAmountEditField_7 = uieditfield(app.HomomorphicPanel, 'numeric');
            app.HomomorphicAmountEditField_7.Limits = [0 Inf];
            app.HomomorphicAmountEditField_7.Value = 30;
            app.DCPanel = uipanel(app.UIFigure);
            app.DCPanel.Title = 'DC';
            app.D0Label_37 = uilabel(app.DCPanel);
            app.D0Label_37.Text = 'Gain (a)';
            app.HomomorphicAmountEditField_28 = uieditfield(app.DCPanel, 'numeric');
            app.HomomorphicAmountEditField_28.Limits = [0 Inf];
            app.HomomorphicAmountEditField_28.Value = 1;
            app.D0Label_38 = uilabel(app.DCPanel);
            app.D0Label_38.Text = 'Offset (b)';
            app.HomomorphicAmountEditField_29 = uieditfield(app.DCPanel, 'numeric');
            app.HomomorphicAmountEditField_29.Limits = [0 Inf];
            app.HomomorphicAmountEditField_29.Value = 50;
            app.IHPFPanel = uipanel(app.UIFigure);
            app.IHPFPanel.Title = 'IHPF';
            app.PaddingLabel_5 = uilabel(app.IHPFPanel);
            app.PaddingLabel_5.Text = 'Padding';
            app.IHPFModeDropDown = uidropdown(app.IHPFPanel);
            app.IHPFModeDropDown.Items = {'None', 'Zero', 'Mirror'};
            app.IHPFModeDropDown.Value = 'None';
            app.D0Label_8 = uilabel(app.IHPFPanel);
            app.D0Label_8.Text = 'D0';
            app.IHPFAmountEditField = uieditfield(app.IHPFPanel, 'numeric');
            app.IHPFAmountEditField.Limits = [0 Inf];
            app.IHPFAmountEditField.Value = 30;
            app.PaddingLabel_6 = uilabel(app.IHPFPanel);
            app.PaddingLabel_6.Text = 'outputMode';
            app.IHPFModeDropDown_2 = uidropdown(app.IHPFPanel);
            app.IHPFModeDropDown_2.Items = {'Clip', 'Normalize'};
            app.IHPFModeDropDown_2.Value = 'Clip';
            app.NotchPanel = uipanel(app.UIFigure);
            app.NotchPanel.Title = 'Notch';
            app.HomomorphicModeDropDown_11 = uidropdown(app.NotchPanel);
            app.HomomorphicModeDropDown_11.Items = {'Ideal', 'Butterworth', 'Gaussian'};
            app.HomomorphicModeDropDown_11.ValueChangedFcn = createCallbackFcn(app, @HomomorphicModeDropDown_11ValueChanged, true);
            app.HomomorphicModeDropDown_11.Value = 'Ideal';
            app.PaddingLabel_14 = uilabel(app.NotchPanel);
            app.PaddingLabel_14.Text = 'Padding';
            app.HomomorphicModeDropDown_10 = uidropdown(app.NotchPanel);
            app.HomomorphicModeDropDown_10.Items = {'None', 'Zero', 'Mirror'};
            app.HomomorphicModeDropDown_10.Value = 'None';
            app.D0Label_30 = uilabel(app.NotchPanel);
            app.D0Label_30.Text = 'k';
            app.HomomorphicAmountEditField_23 = uieditfield(app.NotchPanel, 'numeric');
            app.HomomorphicAmountEditField_23.Limits = [0 Inf];
            app.HomomorphicAmountEditField_23.Value = 4;
            app.D0Label_31 = uilabel(app.NotchPanel);
            app.D0Label_31.Text = 'D0';
            app.HomomorphicAmountEditField_24 = uieditfield(app.NotchPanel, 'numeric');
            app.HomomorphicAmountEditField_24.Limits = [0 Inf];
            app.HomomorphicAmountEditField_24.Value = 10;
            app.D0Label_32 = uilabel(app.NotchPanel);
            app.D0Label_32.Text = 'n';
            app.HomomorphicAmountEditField_25 = uieditfield(app.NotchPanel, 'numeric');
            app.HomomorphicAmountEditField_25.Limits = [0 Inf];
            app.HomomorphicAmountEditField_25.Value = 2;
            app.PaddingLabel_15 = uilabel(app.NotchPanel);
            app.PaddingLabel_15.Text = 'Type';
            app.D0Label_39 = uilabel(app.NotchPanel);
            app.D0Label_39.Text = 'rDC';
            app.HomomorphicAmountEditField_30 = uieditfield(app.NotchPanel, 'numeric');
            app.HomomorphicAmountEditField_30.Limits = [0 Inf];
            app.HomomorphicAmountEditField_30.Value = 10;
            app.CentersLabel = uilabel(app.NotchPanel);
            app.CentersLabel.Text = 'Centers';
            app.NotchCentersTextAreaLabel = uilabel(app.NotchPanel);
            app.NotchCentersTextAreaLabel.Text = 'Text Area';
            app.NotchCentersTextArea = uitextarea(app.NotchPanel);
            app.NotchDetectButton = uibutton(app.NotchPanel, 'push');
            app.NotchDetectButton.ButtonPushedFcn = createCallbackFcn(app, @NotchDetectButtonPushed, true);
            app.NotchDetectButton.Text = 'Detect Spikes';
            app.InputImagePanel = uipanel(app.UIFigure);
            app.InputImagePanel.Title = 'Input Image';
            app.BeforeStats = uilistbox(app.InputImagePanel);
            app.BeforeStats.Items = {'Statistics', 'No statistics yet.'};
            app.BeforeStats.Value = 'Statistics';
            app.InputImageLabel = uilabel(app.InputImagePanel);
            app.InputImageLabel.Text = 'Input Image';
            app.BeforeImage = uiimage(app.InputImagePanel);
            app.FourierSpectrumLabel = uilabel(app.InputImagePanel);
            app.FourierSpectrumLabel.Text = 'Fourier Spectrum';
            app.RInputImage = uiimage(app.InputImagePanel);
            app.GInputImage = uiimage(app.InputImagePanel);
            app.BInputImage = uiimage(app.InputImagePanel);
            app.RLabel = uilabel(app.InputImagePanel);
            app.RLabel.Text = 'R';
            app.GLabel = uilabel(app.InputImagePanel);
            app.GLabel.Text = 'G';
            app.BLabel = uilabel(app.InputImagePanel);
            app.BLabel.Text = 'B';
            app.InspectSpectrumButton = uibutton(app.InputImagePanel, 'push');
            app.InspectSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @InspectSpectrumButtonPushed, true);
            app.InspectSpectrumButton.Text = 'Inspect Spectrum';
            app.MethodsButtonGroup = uibuttongroup(app.UIFigure);
            app.MethodsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MethodsButtonGroupSelectionChanged, true);
            app.MethodsButtonGroup.Title = 'Methods';
            app.ILPFButton = uitogglebutton(app.MethodsButtonGroup);
            app.ILPFButton.Tag = 'brightening';
            app.ILPFButton.Text = 'ILPF';
            app.ILPFButton.Value = true;
            app.IHPFButton = uitogglebutton(app.MethodsButtonGroup);
            app.IHPFButton.Tag = 'equalization';
            app.IHPFButton.Text = 'IHPF';
            app.LowPassFilterLabel = uilabel(app.MethodsButtonGroup);
            app.LowPassFilterLabel.Text = 'Low Pass Filter';
            app.HighPassFilterLabel = uilabel(app.MethodsButtonGroup);
            app.HighPassFilterLabel.Text = 'High Pass Filter';
            app.BrightnessLabel = uilabel(app.MethodsButtonGroup);
            app.BrightnessLabel.Text = 'Brightness';
            app.HomomorphicButton = uitogglebutton(app.MethodsButtonGroup);
            app.HomomorphicButton.Tag = 'mean';
            app.HomomorphicButton.Text = ' Homomorphic';
            app.GLPFButton = uitogglebutton(app.MethodsButtonGroup);
            app.GLPFButton.Tag = 'negative';
            app.GLPFButton.Text = 'GLPF';
            app.BLPFButton = uitogglebutton(app.MethodsButtonGroup);
            app.BLPFButton.Tag = 'log';
            app.BLPFButton.Text = 'BLPF';
            app.DCButton = uitogglebutton(app.MethodsButtonGroup);
            app.DCButton.Tag = 'gaussian';
            app.DCButton.Text = 'DC';
            app.GHPFButton = uitogglebutton(app.MethodsButtonGroup);
            app.GHPFButton.Tag = 'equalization';
            app.GHPFButton.Text = 'GHPF';
            app.BHPFButton = uitogglebutton(app.MethodsButtonGroup);
            app.BHPFButton.Tag = 'equalization';
            app.BHPFButton.Text = 'BHPF';
            app.PeriodicNoiseRestorationLabel = uilabel(app.MethodsButtonGroup);
            app.PeriodicNoiseRestorationLabel.Text = 'Periodic Noise Restoration';
            app.IdealButton = uitogglebutton(app.MethodsButtonGroup);
            app.IdealButton.Tag = 'mean';
            app.IdealButton.Text = 'Ideal';
            app.Label = uilabel(app.MethodsButtonGroup);
            app.Label.Text = 'Motion Blur Restoration';
            app.PSFButton = uitogglebutton(app.MethodsButtonGroup);
            app.PSFButton.Tag = 'mean';
            app.PSFButton.Text = 'PSF';
            app.ButterworthButton = uitogglebutton(app.MethodsButtonGroup);
            app.ButterworthButton.Tag = 'mean';
            app.ButterworthButton.Text = 'Butterworth';
            app.GaussianButton = uitogglebutton(app.MethodsButtonGroup);
            app.GaussianButton.Tag = 'mean';
            app.GaussianButton.Text = 'Gaussian';
            app.NotchButton = uitogglebutton(app.MethodsButtonGroup);
            app.NotchButton.Tag = 'mean';
            app.NotchButton.Text = 'Notch';
            app.InverseButton = uitogglebutton(app.MethodsButtonGroup);
            app.InverseButton.Tag = 'mean';
            app.InverseButton.Text = 'Inverse';
            app.WienerButton = uitogglebutton(app.MethodsButtonGroup);
            app.WienerButton.Tag = 'mean';
            app.WienerButton.Text = 'Wiener';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Icon = fullfile(pathToMLAPP, 'assets', 'play.png');
            app.ApplyButton.Text = 'Apply';
            app.UndoButton = uibutton(app.UIFigure, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Icon = fullfile(pathToMLAPP, 'assets', 'undo.png');
            app.UndoButton.Text = 'Undo';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Icon = fullfile(pathToMLAPP, 'assets', 'reset.png');
            app.ResetButton.Text = 'Reset';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = fullfile(pathToMLAPP, 'assets', 'save.png');
            app.SaveButton.Text = 'Save';
            app.InputImagePanel_2 = uipanel(app.UIFigure);
            app.InputImagePanel_2.Title = 'Input Image';
            app.UploadImageButton = uibutton(app.InputImagePanel_2, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Icon = fullfile(pathToMLAPP, 'assets', 'folder.png');
            app.UploadImageButton.Text = 'Upload from Files';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'assets', 'app-logo.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'assets', 'app-copy.png');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'assets', '4-diamonds.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'assets', 'diamond.png');
            app.Image3_2 = uiimage(app.UIFigure);
            app.Image3_2.ImageSource = fullfile(pathToMLAPP, 'assets', 'diamond.png');
            app.Image4_2 = uiimage(app.UIFigure);
            app.Image4_2.ImageSource = fullfile(pathToMLAPP, 'assets', '4-diamonds.png');
            app.Image4_3 = uiimage(app.UIFigure);
            app.Image4_3.ImageSource = fullfile(pathToMLAPP, 'assets', '4-diamonds.png');
            app.FilterResponsePanel = uipanel(app.UIFigure);
            app.FilterResponsePanel.Title = 'Filter Response';
            app.BeforeStats_3 = uilistbox(app.FilterResponsePanel);
            app.BeforeStats_3.Items = {'Statistics', 'No statistics yet.'};
            app.BeforeStats_3.Value = 'Statistics';
            app.BeforeImage_9 = uiimage(app.FilterResponsePanel);
            app.Image4_5 = uiimage(app.UIFigure);
            app.Image4_5.ImageSource = fullfile(pathToMLAPP, 'assets', '4-diamonds.png');
            app.referenceImagePanel = uipanel(app.UIFigure);
            app.referenceImagePanel.Title = 'reference Image';
            app.UploadImageButton_2 = uibutton(app.referenceImagePanel, 'push');
            app.UploadImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButton_2Pushed, true);
            app.UploadImageButton_2.Icon = fullfile(pathToMLAPP, 'assets', 'folder.png');
            app.UploadImageButton_2.Text = 'Upload from Files';
            app.Image4_6 = uiimage(app.UIFigure);
            app.Image4_6.ImageSource = fullfile(pathToMLAPP, 'assets', '4-diamonds.png');
            app.Image3_3 = uiimage(app.UIFigure);
            app.Image3_3.ImageSource = fullfile(pathToMLAPP, 'assets', 'diamond.png');
            app.ResultImagePanel = uipanel(app.UIFigure);
            app.ResultImagePanel.Title = 'Result Image';
            app.BeforeStats_4 = uilistbox(app.ResultImagePanel);
            app.BeforeStats_4.Items = {'Statistics', 'No statistics yet.'};
            app.BeforeStats_4.Value = 'Statistics';
            app.InputImageLabel_2 = uilabel(app.ResultImagePanel);
            app.InputImageLabel_2.Text = 'Input Image';
            app.BeforeImage_10 = uiimage(app.ResultImagePanel);
            app.FourierSpectrumLabel_2 = uilabel(app.ResultImagePanel);
            app.FourierSpectrumLabel_2.Text = 'Fourier Spectrum';
            app.RResultImage = uiimage(app.ResultImagePanel);
            app.GResultImage = uiimage(app.ResultImagePanel);
            app.BResultImage = uiimage(app.ResultImagePanel);
            app.RLabel_2 = uilabel(app.ResultImagePanel);
            app.RLabel_2.Text = 'R';
            app.GLabel_2 = uilabel(app.ResultImagePanel);
            app.GLabel_2.Text = 'G';
            app.BLabel_2 = uilabel(app.ResultImagePanel);
            app.BLabel_2.Text = 'B';
            app.ReferenceImagePanel = uipanel(app.UIFigure);
            app.ReferenceImagePanel.Title = 'Reference Image';
            app.BeforeStats_5 = uilistbox(app.ReferenceImagePanel);
            app.BeforeStats_5.Items = {'Statistics', 'No statistics yet.'};
            app.BeforeStats_5.Value = 'Statistics';
            app.InputImageLabel_3 = uilabel(app.ReferenceImagePanel);
            app.InputImageLabel_3.Text = 'Input Image';
            app.BeforeImage_14 = uiimage(app.ReferenceImagePanel);
            app.FourierSpectrumLabel_3 = uilabel(app.ReferenceImagePanel);
            app.FourierSpectrumLabel_3.Text = 'Fourier Spectrum';
            app.RReferenceImage = uiimage(app.ReferenceImagePanel);
            app.GReferenceImage = uiimage(app.ReferenceImagePanel);
            app.BReferenceImage = uiimage(app.ReferenceImagePanel);
            app.RLabel_3 = uilabel(app.ReferenceImagePanel);
            app.RLabel_3.Text = 'R';
            app.GLabel_3 = uilabel(app.ReferenceImagePanel);
            app.GLabel_3.Text = 'G';
            app.BLabel_3 = uilabel(app.ReferenceImagePanel);
            app.BLabel_3.Text = 'B';
            app.Image4_8 = uiimage(app.UIFigure);
            app.Image4_8.ImageSource = fullfile(pathToMLAPP, 'assets', '4-diamonds.png');
            app.Image4_7 = uiimage(app.UIFigure);
            app.Image4_7.ImageSource = fullfile(pathToMLAPP, 'assets', '4-diamonds.png');
            app.InversePanel = uipanel(app.UIFigure);
            app.InversePanel.Title = 'Inverse';
            app.PaddingLabel_10 = uilabel(app.InversePanel);
            app.PaddingLabel_10.Text = 'Mode';
            app.HomomorphicModeDropDown_6 = uidropdown(app.InversePanel);
            app.HomomorphicModeDropDown_6.Items = {'None', 'Threshold', 'Radius'};
            app.HomomorphicModeDropDown_6.ValueChangedFcn = createCallbackFcn(app, @HomomorphicModeDropDown_6ValueChanged, true);
            app.HomomorphicModeDropDown_6.Value = 'Threshold';
            app.D0Label_15 = uilabel(app.InversePanel);
            app.D0Label_15.Text = 'R';
            app.HomomorphicAmountEditField_8 = uieditfield(app.InversePanel, 'numeric');
            app.HomomorphicAmountEditField_8.Limits = [0 Inf];
            app.HomomorphicAmountEditField_8.Value = 60;
            app.D0Label_18 = uilabel(app.InversePanel);
            app.D0Label_18.Text = 'ε';
            app.HomomorphicAmountEditField_11 = uieditfield(app.InversePanel, 'numeric');
            app.HomomorphicAmountEditField_11.Limits = [0 Inf];
            app.HomomorphicAmountEditField_11.Value = 0.1;
            app.D0Label_40 = uilabel(app.InversePanel);
            app.D0Label_40.Text = 'L';
            app.InverseLEditField = uieditfield(app.InversePanel, 'numeric');
            app.InverseLEditField.Limits = [1 Inf];
            app.InverseLEditField.Value = 10;
            app.D0Label_41 = uilabel(app.InversePanel);
            app.D0Label_41.Text = 'θ';
            app.InverseThetaEditField = uieditfield(app.InversePanel, 'numeric');
            app.InverseThetaEditField.Limits = [-180 180];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end